classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SaveAsMenu                     matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        LocalFilterTab                 matlab.ui.container.Tab
        GoButton_1                     matlab.ui.control.Button
        TabGroup2_2                    matlab.ui.container.TabGroup
        AdapMedTab                     matlab.ui.container.Tab
        MaxWindowSizeSliderLabel       matlab.ui.control.Label
        MaxWindowSizeSlider            matlab.ui.control.Slider
        AdaptiveMedianFilterLabel_2    matlab.ui.control.Label
        StartWindowSizeSliderLabel     matlab.ui.control.Label
        StartWindowSizeSlider          matlab.ui.control.Slider
        AriMeanTab                     matlab.ui.container.Tab
        WindowSizeSlider_3Label        matlab.ui.control.Label
        WindowSizeSliderAri            matlab.ui.control.Slider
        ArithmeticMeanFilterLabel      matlab.ui.control.Label
        GeoMeanTab                     matlab.ui.container.Tab
        WindowSizeSlider_4Label        matlab.ui.control.Label
        WindowSizeSliderGeo            matlab.ui.control.Slider
        GeometricMeanFilterLabel       matlab.ui.control.Label
        HarMeanTab                     matlab.ui.container.Tab
        WindowSizeSlider_2Label        matlab.ui.control.Label
        WindowSizeSliderHar            matlab.ui.control.Slider
        HarmonicMeanFilterLabel        matlab.ui.control.Label
        CHarMeanTab                    matlab.ui.container.Tab
        WindowSizeSliderLabel          matlab.ui.control.Label
        WindowSizeSliderCHar           matlab.ui.control.Slider
        ContraHarmonicMeanFilterLabel  matlab.ui.control.Label
        QSpinnerLabel                  matlab.ui.control.Label
        QSpinner                       matlab.ui.control.Spinner
        InvFilterTab                   matlab.ui.container.Tab
        GoButton_2                     matlab.ui.control.Button
        TabGroup2                      matlab.ui.container.TabGroup
        GaussianTab                    matlab.ui.container.Tab
        aSpinner                       matlab.ui.control.Spinner
        aSpinner_2Label                matlab.ui.control.Label
        kSpinner                       matlab.ui.control.Spinner
        kSpinner_2Label                matlab.ui.control.Label
        bSpinner                       matlab.ui.control.Spinner
        bSpinner_2Label                matlab.ui.control.Label
        GLabel                         matlab.ui.control.Label
        DefocusedTab                   matlab.ui.container.Tab
        RSpinner                       matlab.ui.control.Spinner
        RSpinnerLabel                  matlab.ui.control.Label
        GLabel_3                       matlab.ui.control.Label
        LMTab                          matlab.ui.container.Tab
        LinearMotionLabel              matlab.ui.control.Label
        GLabel_2                       matlab.ui.control.Label
        TSpinner                       matlab.ui.control.Spinner
        TSpinnerLabel                  matlab.ui.control.Label
        aSpinner_2                     matlab.ui.control.Spinner
        aSpinner_2Label_2              matlab.ui.control.Label
        bSpinner_2                     matlab.ui.control.Spinner
        bSpinner_2Label_2              matlab.ui.control.Label
        CradiusSpinner                 matlab.ui.control.Spinner
        CradiusSpinnerLabel            matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        alpSpinner                     matlab.ui.control.Spinner
        KSpinnerLabel                  matlab.ui.control.Label
        KSpinner                       matlab.ui.control.Spinner
        WaveletTab                     matlab.ui.container.Tab
        GoButton_3                     matlab.ui.control.Button
        p1Spinner                      matlab.ui.control.Spinner
        p1Label                        matlab.ui.control.Label
        p2Spinner                      matlab.ui.control.Spinner
        p2Label                        matlab.ui.control.Label
        TVTab                          matlab.ui.container.Tab
        GoButton_4                     matlab.ui.control.Button
        epSpinner                      matlab.ui.control.Spinner
        epSpinnerLabel                 matlab.ui.control.Label
        dtSpinner                      matlab.ui.control.Spinner
        dtSpinnerLabel                 matlab.ui.control.Label
        lamSpinner                     matlab.ui.control.Spinner
        lamSpinnerLabel                matlab.ui.control.Label
        CSpinner                       matlab.ui.control.Spinner
        CSpinnerLabel                  matlab.ui.control.Label
        iterationSpinner               matlab.ui.control.Spinner
        iterationSpinnerLabel          matlab.ui.control.Label
        AnisoDiffusionTab              matlab.ui.container.Tab
        GoButton_5                     matlab.ui.control.Button
        kSpinner_2                     matlab.ui.control.Spinner
        kSpinner_2Label_2              matlab.ui.control.Label
        lambdaSpinner                  matlab.ui.control.Spinner
        lambdaSpinnerLabel             matlab.ui.control.Label
        iterationSpinner_2             matlab.ui.control.Spinner
        iterationSpinner_2Label        matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        SpectrumButton                 matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img_reg % Description
%         img_processing
        img_rev_func % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize
            addpath(genpath('.'));
            app.img_rev_func = imread('FilterFunc.png');
            imshow([uint8([255, 255])], 'Parent', app.UIAxes_2);
%             img_H = imread('FilterFunc.png');
%             imshow(img_H, 'Parent', app.UIAxes_2);

%             try
%                 app.img_processing = ImageClass('res/processing.png');
%             catch ME
%                 fprintf('%s', ME.identifier);
%             end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [f_name, f_path] = uigetfile('*.*', 'Select file');
            img_path = strcat(f_path, f_name);
            app.img_reg = ImageRegister(img_path);
            imshow(app.img_reg.getimg().getimg_uint8(), 'Parent', app.UIAxes);
            app.Label_2.Text = app.img_reg.getimg().getsize_char();
        end

        % Button pushed function: SpectrumButton
        function SpectrumButtonPushed(app, event)
            sp = app.img_reg.getimg().getspectrum();
            display_spctrm(sp);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.img_reg = app.img_reg.reset();
            imshow(app.img_reg.getimg().getimg_uint8(), 'Parent', app.UIAxes);
        end

        % Value changed function: StartWindowSizeSlider
        function StartWindowSizeSliderValueChanged(app, event)
            value = round(app.StartWindowSizeSlider.Value);
            sz = app.img_reg.getimg().getsize();
            if mod(value, 2) == 0
                value = value - 1;
            end
            if value > min(sz(1:2))
                value = min(sz(1:2));
            end
            app.StartWindowSizeSlider.Value = value;
            app.MaxWindowSizeSlider.Value = value;
        end

        % Value changed function: MaxWindowSizeSlider
        function MaxWindowSizeSliderValueChanged(app, event)
            value = round(app.MaxWindowSizeSlider.Value);
            sz = app.img_reg.getimg().getsize();
            if mod(value, 2) == 0
                value = value - 1;
            end
            if value > min(sz(1:2))
                value = min(sz(1:2));
            end
            if value < app.StartWindowSizeSlider.Value
                value = app.StartWindowSizeSlider.Value;
            end
            app.MaxWindowSizeSlider.Value = value;
        end

        % Button pushed function: GoButton_1
        function GoButton_1Pushed(app, event)
            app.Label.Text = 'Processing ...';
            pause(0.1);
            img0 = app.img_reg.getimg().getimg_uint8();
            switch app.TabGroup2_2.SelectedTab
                case app.AdapMedTab
                    v_lo = app.StartWindowSizeSlider.Value;
                    v_hi = app.MaxWindowSizeSlider.Value;
                    varargin2run = {[v_lo, v_lo], [v_hi, v_hi]};
                    f_ = @adaptive_median_3d;
                case app.AriMeanTab
                    win_size = app.WindowSizeSliderAri.Value;
                    varargin2run = {@mean, [win_size, win_size], 'all'};
                    f_ = @window_filter;
                case app.GeoMeanTab
                    win_size = app.WindowSizeSliderGeo.Value;
                    varargin2run = {@geomean, [win_size, win_size], 'all'};
                    f_ = @window_filter;
                case app.HarMeanTab
                    win_size = app.WindowSizeSliderHar.Value;
                    varargin2run = {@harmmean, [win_size, win_size], 'all'};
                    f_ = @window_filter;
                case app.CHarMeanTab
                    win_size = app.WindowSizeSliderCHar.Value;
                    Q = app.QSpinner.Value;
                    varargin2run = {@contraharmonic_mean, [win_size, win_size], Q};
                    f_ = @window_filter;
            end
            img1 = feval(f_, img0, varargin2run{:});
            app.img_reg = app.img_reg.newimg(img1);
            imshow(img1, 'Parent', app.UIAxes);
            app.Label.Text = '';
        end

        % Button pushed function: GoButton_2
        function GoButton_2Pushed(app, event)
            app.Label.Text = 'Processing ...';
            pause(0.1);
            img0 = app.img_reg.getimg().getimg_uint8();
            C = app.CradiusSpinner.Value;
            K = app.KSpinner.Value;
            alp = app.alpSpinner.Value;
            f_ = @multiplicative_inv;
            varargin2run = {[], C, -1, 0, K, alp};
            switch app.TabGroup2.SelectedTab
                case app.GaussianTab
                    k = app.kSpinner.Value;
                    a = app.aSpinner.Value;
                    b = app.bSpinner.Value;              
                    varargin2H = {k, a, b};            
                    H_f_ = @gaussian_deg;
                case app.LMTab
                    T = app.TSpinner.Value;
                    a = app.aSpinner_2.Value;
                    b = app.bSpinner_2.Value;
                    varargin2H = {T, a, b};
                    H_f_ = @linear_motion_deg;
                case app.DefocusedTab
                    R = app.RSpinner.Value;
                    varargin2H = {R};
                    H_f_ = @defocusing_deg;
            end 
            sz = app.img_reg.getimg().getsize();
            H_ = getH(sz, H_f_, varargin2H{:});
            varargin2run{1} = H_;
            img1 = feval(f_, img0, varargin2run{:});
            app.img_reg = app.img_reg.newimg(img1);
            imshow(img1, 'Parent', app.UIAxes);
            app.Label.Text = '';
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            [f_name, f_path] = uiputfile({'*.jpg'; '*.png'; '*.tif'}, 'Save as');
            path_ = strcat(f_path, f_name);
            types_ = split(f_name, '.');
            imwrite(app.img_reg.getimg().getimg_uint8(), path_, types_{end});
        end

        % Button pushed function: GoButton_3
        function GoButton_3Pushed(app, event)
            app.Label.Text = 'Processing ...';
            pause(0.1);
            img0 = app.img_reg.getimg().getimg_uint8();
            p1 = app.p1Spinner.Value; p2 = app.p2Spinner.Value;
            p = [p1, p2];
%             n1 = app.n1Spinner.Value; n2 = app.n2Spinner.Value;
%             n = [n1, n2];
            img1 = waveletdenoise(img0, p);
            app.img_reg = app.img_reg.newimg(img1);
            imshow(img1, 'Parent', app.UIAxes);
            app.Label.Text = '';
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.InvFilterTab
%                 img_H = imread('FilterFunc.png');
                imshow(app.img_rev_func, 'Parent', app.UIAxes_2);
            else
                imshow(uint8([255, 255]), 'Parent', app.UIAxes_2);
            end
        end

        % Button pushed function: GoButton_4
        function GoButton_4Pushed(app, event)
            app.Label.Text = 'Processing ...';
            pause(0.1);
            img0 = app.img_reg.getimg().getimg_uint8();
            ep = app.epSpinner.Value;
            dt = app.dtSpinner.Value;
            lam = app.lamSpinner.Value;
            C = app.CSpinner.Value;
            iter = app.iterationSpinner.Value;
            img1 = tvdenoising(img0, ep, dt, lam, C, round(iter));
            app.img_reg = app.img_reg.newimg(img1);
            imshow(img1, 'Parent', app.UIAxes);
            app.Label.Text = '';
        end

        % Button pushed function: GoButton_5
        function GoButton_5Pushed(app, event)
            app.Label.Text = 'Processing ...';
            pause(0.1);
            img0 = app.img_reg.getimg().getimg_uint8();
            k = app.kSpinner_2.Value;
            lambda = app.lambdaSpinner.Value;
            iter = app.iterationSpinner_2.Value;
            img1 = anisotropic_diffusion(img0, k, lambda, round(iter));
            app.img_reg = app.img_reg.newimg(img1);
            imshow(img1, 'Parent', app.UIAxes);
            app.Label.Text = '';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LocalFilterTab = uitab(app.TabGroup);
            app.LocalFilterTab.Title = 'Local Filter';
            app.GoButton_1 = uibutton(app.LocalFilterTab, 'push');
            app.GoButton_1.ButtonPushedFcn = createCallbackFcn(app, @GoButton_1Pushed, true);
            app.GoButton_1.Text = 'Go';
            app.TabGroup2_2 = uitabgroup(app.LocalFilterTab);
            app.AdapMedTab = uitab(app.TabGroup2_2);
            app.AdapMedTab.Title = 'AdapMed';
            app.MaxWindowSizeSliderLabel = uilabel(app.AdapMedTab);
            app.MaxWindowSizeSliderLabel.Text = 'Max Window Size';
            app.MaxWindowSizeSlider = uislider(app.AdapMedTab);
            app.MaxWindowSizeSlider.Limits = [3 25];
            app.MaxWindowSizeSlider.MajorTicks = [3 5 7 9 11 13 15 17 19 21 23 25];
            app.MaxWindowSizeSlider.MajorTickLabels = {'3', '5', '7', '9', '11', '13', '15', '17', '19', '21', '23', '25', ''};
            app.MaxWindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MaxWindowSizeSliderValueChanged, true);
            app.MaxWindowSizeSlider.MinorTicks = [];
            app.MaxWindowSizeSlider.Value = 3;
            app.AdaptiveMedianFilterLabel_2 = uilabel(app.AdapMedTab);
            app.AdaptiveMedianFilterLabel_2.Text = {'Adaptive Median '; 'Filter'};
            app.StartWindowSizeSliderLabel = uilabel(app.AdapMedTab);
            app.StartWindowSizeSliderLabel.Text = 'Start Window Size';
            app.StartWindowSizeSlider = uislider(app.AdapMedTab);
            app.StartWindowSizeSlider.Limits = [3 25];
            app.StartWindowSizeSlider.MajorTicks = [3 5 7 9 11 13 15 17 19 21 23 25];
            app.StartWindowSizeSlider.MajorTickLabels = {'3', '5', '7', '9', '11', '13', '15', '17', '19', '21', '23', '25', ''};
            app.StartWindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @StartWindowSizeSliderValueChanged, true);
            app.StartWindowSizeSlider.MinorTicks = [];
            app.StartWindowSizeSlider.Value = 3;
            app.AriMeanTab = uitab(app.TabGroup2_2);
            app.AriMeanTab.Title = 'AriMean';
            app.WindowSizeSlider_3Label = uilabel(app.AriMeanTab);
            app.WindowSizeSlider_3Label.Text = 'Window Size';
            app.WindowSizeSliderAri = uislider(app.AriMeanTab);
            app.WindowSizeSliderAri.Limits = [3 25];
            app.WindowSizeSliderAri.MajorTicks = [3 5 7 9 11 13 15 17 19 21 23 25];
            app.WindowSizeSliderAri.MajorTickLabels = {'3', '5', '7', '9', '11', '13', '15', '17', '19', '21', '23', '25', ''};
            app.WindowSizeSliderAri.MinorTicks = [];
            app.WindowSizeSliderAri.Value = 3;
            app.ArithmeticMeanFilterLabel = uilabel(app.AriMeanTab);
            app.ArithmeticMeanFilterLabel.Text = {'Arithmetic Mean'; ' Filter'; ''};
            app.GeoMeanTab = uitab(app.TabGroup2_2);
            app.GeoMeanTab.Title = 'GeoMean';
            app.WindowSizeSlider_4Label = uilabel(app.GeoMeanTab);
            app.WindowSizeSlider_4Label.Text = 'Window Size';
            app.WindowSizeSliderGeo = uislider(app.GeoMeanTab);
            app.WindowSizeSliderGeo.Limits = [3 25];
            app.WindowSizeSliderGeo.MajorTicks = [3 5 7 9 11 13 15 17 19 21 23 25];
            app.WindowSizeSliderGeo.MajorTickLabels = {'3', '5', '7', '9', '11', '13', '15', '17', '19', '21', '23', '25', ''};
            app.WindowSizeSliderGeo.MinorTicks = [];
            app.WindowSizeSliderGeo.Value = 3;
            app.GeometricMeanFilterLabel = uilabel(app.GeoMeanTab);
            app.GeometricMeanFilterLabel.Text = {'Geometric Mean '; 'Filter'};
            app.HarMeanTab = uitab(app.TabGroup2_2);
            app.HarMeanTab.Title = 'HarMean';
            app.WindowSizeSlider_2Label = uilabel(app.HarMeanTab);
            app.WindowSizeSlider_2Label.Text = 'Window Size';
            app.WindowSizeSliderHar = uislider(app.HarMeanTab);
            app.WindowSizeSliderHar.Limits = [3 25];
            app.WindowSizeSliderHar.MajorTicks = [3 5 7 9 11 13 15 17 19 21 23 25];
            app.WindowSizeSliderHar.MajorTickLabels = {'3', '5', '7', '9', '11', '13', '15', '17', '19', '21', '23', '25', ''};
            app.WindowSizeSliderHar.MinorTicks = [];
            app.WindowSizeSliderHar.Value = 3;
            app.HarmonicMeanFilterLabel = uilabel(app.HarMeanTab);
            app.HarmonicMeanFilterLabel.Text = {'Harmonic Mean'; ' Filter'; ''};
            app.CHarMeanTab = uitab(app.TabGroup2_2);
            app.CHarMeanTab.Title = 'CHarMean';
            app.WindowSizeSliderLabel = uilabel(app.CHarMeanTab);
            app.WindowSizeSliderLabel.Text = 'Window Size';
            app.WindowSizeSliderCHar = uislider(app.CHarMeanTab);
            app.WindowSizeSliderCHar.Limits = [3 25];
            app.WindowSizeSliderCHar.MajorTicks = [3 5 7 9 11 13 15 17 19 21 23 25];
            app.WindowSizeSliderCHar.MajorTickLabels = {'3', '5', '7', '9', '11', '13', '15', '17', '19', '21', '23', '25', ''};
            app.WindowSizeSliderCHar.MinorTicks = [];
            app.WindowSizeSliderCHar.Value = 3;
            app.ContraHarmonicMeanFilterLabel = uilabel(app.CHarMeanTab);
            app.ContraHarmonicMeanFilterLabel.Text = {'ContraHarmonic '; 'Mean Filter'; ''};
            app.QSpinnerLabel = uilabel(app.CHarMeanTab);
            app.QSpinnerLabel.Text = 'Q';
            app.QSpinner = uispinner(app.CHarMeanTab);
            app.QSpinner.Step = 0.1;
            app.QSpinner.Value = 1;
            app.InvFilterTab = uitab(app.TabGroup);
            app.InvFilterTab.Title = 'InvFilter';
            app.GoButton_2 = uibutton(app.InvFilterTab, 'push');
            app.GoButton_2.ButtonPushedFcn = createCallbackFcn(app, @GoButton_2Pushed, true);
            app.GoButton_2.Text = 'Go';
            app.TabGroup2 = uitabgroup(app.InvFilterTab);
            app.GaussianTab = uitab(app.TabGroup2);
            app.GaussianTab.Title = 'Gaussian';
            app.aSpinner = uispinner(app.GaussianTab);
            app.aSpinner.Step = 0.0001;
            app.aSpinner.Value = 0.0001;
            app.aSpinner_2Label = uilabel(app.GaussianTab);
            app.aSpinner_2Label.Text = 'a';
            app.kSpinner = uispinner(app.GaussianTab);
            app.kSpinner.Step = 0.1;
            app.kSpinner.Value = 1;
            app.kSpinner_2Label = uilabel(app.GaussianTab);
            app.kSpinner_2Label.Text = 'k';
            app.bSpinner = uispinner(app.GaussianTab);
            app.bSpinner.Step = 0.01;
            app.bSpinner.Value = 1;
            app.bSpinner_2Label = uilabel(app.GaussianTab);
            app.bSpinner_2Label.Text = 'b';
            app.GLabel = uilabel(app.GaussianTab);
            app.GLabel.Text = {'H(u‚v) ='; ' k·exp(-a(u2+v2)ᵇ)'};
            app.DefocusedTab = uitab(app.TabGroup2);
            app.DefocusedTab.Title = 'Defocused';
            app.RSpinner = uispinner(app.DefocusedTab);
            app.RSpinner.Value = 5;
            app.RSpinnerLabel = uilabel(app.DefocusedTab);
            app.RSpinnerLabel.Text = 'R';
            app.GLabel_3 = uilabel(app.DefocusedTab);
            app.GLabel_3.Text = {'h(x‚y)=1/(πR)‚'; 'when R<√(x²+y²)'; 'else‚ 0'};
            app.LMTab = uitab(app.TabGroup2);
            app.LMTab.Title = 'LM';
            app.LinearMotionLabel = uilabel(app.LMTab);
            app.LinearMotionLabel.Text = {'Linear'; 'Motion'};
            app.GLabel_2 = uilabel(app.LMTab);
            app.GLabel_2.Text = {'H(u‚v) ='; '(T/X)(X/sin(X))·exp(jπX)'; 'where‚'; 'X=(π(ua+vb))'};
            app.TSpinner = uispinner(app.LMTab);
            app.TSpinner.Step = 0.0001;
            app.TSpinner.Value = 0.1;
            app.TSpinnerLabel = uilabel(app.LMTab);
            app.TSpinnerLabel.Text = 'T';
            app.aSpinner_2 = uispinner(app.LMTab);
            app.aSpinner_2.Step = 0.0001;
            app.aSpinner_2.Value = 0.01;
            app.aSpinner_2Label_2 = uilabel(app.LMTab);
            app.aSpinner_2Label_2.Text = 'a';
            app.bSpinner_2 = uispinner(app.LMTab);
            app.bSpinner_2.Step = 0.0001;
            app.bSpinner_2.Value = 0.01;
            app.bSpinner_2Label_2 = uilabel(app.LMTab);
            app.bSpinner_2Label_2.Text = 'b';
            app.CradiusSpinner = uispinner(app.InvFilterTab);
            app.CradiusSpinner.Limits = [-1 Inf];
            app.CradiusSpinner.Value = -1;
            app.CradiusSpinnerLabel = uilabel(app.InvFilterTab);
            app.CradiusSpinnerLabel.Text = 'C (radius)';
            app.Label_3 = uilabel(app.InvFilterTab);
            app.Label_3.Text = 'α';
            app.alpSpinner = uispinner(app.InvFilterTab);
            app.alpSpinner.Step = 0.1;
            app.alpSpinner.Limits = [0 1];
            app.KSpinnerLabel = uilabel(app.InvFilterTab);
            app.KSpinnerLabel.Text = 'K';
            app.KSpinner = uispinner(app.InvFilterTab);
            app.WaveletTab = uitab(app.TabGroup);
            app.WaveletTab.Title = 'Wavelet';
            app.GoButton_3 = uibutton(app.WaveletTab, 'push');
            app.GoButton_3.ButtonPushedFcn = createCallbackFcn(app, @GoButton_3Pushed, true);
            app.GoButton_3.Text = 'Go';
            app.p1Spinner = uispinner(app.WaveletTab);
            app.p1Spinner.Step = 0.01;
            app.p1Spinner.Value = 10.12;
            app.p1Label = uilabel(app.WaveletTab);
            app.p1Label.Text = 'p1';
            app.p2Spinner = uispinner(app.WaveletTab);
            app.p2Spinner.Step = 0.01;
            app.p2Spinner.Value = 23.28;
            app.p2Label = uilabel(app.WaveletTab);
            app.p2Label.Text = 'p2';
            app.TVTab = uitab(app.TabGroup);
            app.TVTab.Title = 'TV';
            app.GoButton_4 = uibutton(app.TVTab, 'push');
            app.GoButton_4.ButtonPushedFcn = createCallbackFcn(app, @GoButton_4Pushed, true);
            app.GoButton_4.Text = 'Go';
            app.epSpinner = uispinner(app.TVTab);
            app.epSpinner.Step = 0.01;
            app.epSpinner.Value = 1;
            app.epSpinnerLabel = uilabel(app.TVTab);
            app.epSpinnerLabel.Text = 'ep';
            app.dtSpinner = uispinner(app.TVTab);
            app.dtSpinner.Step = 0.01;
            app.dtSpinner.Value = 0.2;
            app.dtSpinnerLabel = uilabel(app.TVTab);
            app.dtSpinnerLabel.Text = 'dt';
            app.lamSpinner = uispinner(app.TVTab);
            app.lamSpinner.Step = 0.01;
            app.lamSpinnerLabel = uilabel(app.TVTab);
            app.lamSpinnerLabel.Text = 'lam';
            app.CSpinner = uispinner(app.TVTab);
            app.CSpinner.Step = 0.01;
            app.CSpinnerLabel = uilabel(app.TVTab);
            app.CSpinnerLabel.Text = 'C';
            app.iterationSpinner = uispinner(app.TVTab);
            app.iterationSpinner.Limits = [1 Inf];
            app.iterationSpinner.RoundFractionalValues = 'on';
            app.iterationSpinner.ValueDisplayFormat = '%d';
            app.iterationSpinner.Value = 5;
            app.iterationSpinnerLabel = uilabel(app.TVTab);
            app.iterationSpinnerLabel.Text = 'iteration';
            app.AnisoDiffusionTab = uitab(app.TabGroup);
            app.AnisoDiffusionTab.Title = 'AnisoDiffusion';
            app.GoButton_5 = uibutton(app.AnisoDiffusionTab, 'push');
            app.GoButton_5.ButtonPushedFcn = createCallbackFcn(app, @GoButton_5Pushed, true);
            app.GoButton_5.Text = 'Go';
            app.kSpinner_2 = uispinner(app.AnisoDiffusionTab);
            app.kSpinner_2.Step = 0.1;
            app.kSpinner_2.Value = 15;
            app.kSpinner_2Label_2 = uilabel(app.AnisoDiffusionTab);
            app.kSpinner_2Label_2.Text = 'k';
            app.lambdaSpinner = uispinner(app.AnisoDiffusionTab);
            app.lambdaSpinner.Step = 0.01;
            app.lambdaSpinner.Value = 0.15;
            app.lambdaSpinnerLabel = uilabel(app.AnisoDiffusionTab);
            app.lambdaSpinnerLabel.Text = 'lambda';
            app.iterationSpinner_2 = uispinner(app.AnisoDiffusionTab);
            app.iterationSpinner_2.Limits = [1 Inf];
            app.iterationSpinner_2.ValueDisplayFormat = '%d';
            app.iterationSpinner_2.Value = 20;
            app.iterationSpinner_2Label = uilabel(app.AnisoDiffusionTab);
            app.iterationSpinner_2Label.Text = 'iteration';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SpectrumButton = uibutton(app.UIFigure, 'push');
            app.SpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButtonPushed, true);
            app.SpectrumButton.Text = 'Spectrum';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end